classdef duckShooterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DuckShooterUIFigure            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        DroneImage                     matlab.ui.control.Image
        DroneDropDown                  matlab.ui.control.DropDown
        DroneDropDownLabel             matlab.ui.control.Label
        PercentKilledEditField         matlab.ui.control.NumericEditField
        PercentKilledEditFieldLabel    matlab.ui.control.Label
        DronesEngagedEditField         matlab.ui.control.NumericEditField
        DronesEngagedEditFieldLabel    matlab.ui.control.Label
        DronesDestroyedEditField       matlab.ui.control.NumericEditField
        DronesDestroyedEditFieldLabel  matlab.ui.control.Label
        SimulationResultsLabel         matlab.ui.control.Label
        ShowAnimationCheckBox          matlab.ui.control.CheckBox
        EndButton                      matlab.ui.control.Button
        SimulationInputsLabel          matlab.ui.control.Label
        DroneCountEditField            matlab.ui.control.NumericEditField
        DroneCountEditFieldLabel       matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        SimulationControlLabel         matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        LaserDropDown                  matlab.ui.control.DropDown
        LaserDropDownLabel             matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        LaserPowerLevelGauge           matlab.ui.control.LinearGauge
        LaserPowerLevelGaugeLabel      matlab.ui.control.Label
        LaserTemperatureGauge          matlab.ui.control.Gauge
        LaserTemperatureGaugeLabel     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        engine % Description


    end

    methods (Access = public)

        function axesH = initAxes(~,axesH)
            % Access the UIAxes component and update its properties
            axesH.XLabel.String = '';  % Remove X label
            axesH.YLabel.String = '';  % Remove Y label

            % Remove ticks and tick labels
            xticks(axesH, []);
            yticks(axesH, []);

            % Set the background color to black
            axesH.Color = 'black';  % Set the background color of the axes to black

            % Add a black border
            axesH.Box = 'on';  % Turn on the box (border)
            axesH.XColor = 'black';  % Set the color of the X axis line (black)
            axesH.YColor = 'black';  % Set the color of the Y axis line (black)
            axesH.LineWidth = 2;  % Optional: make the border thicker
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the DBSimulator instance
            app.engine = DBSimulator(app.UIAxes);  % Pass the UIAxes handle for visualization
            
            % Set the default value of the dropdown to 'Predator'
            app.DroneDropDown.Value = 'Predator';

            % Manually update the image based on the selected value
            updateDroneImage(app);
        end

        % Value changed function: DroneDropDown
        function DroneDropDownValueChanged(app, event)
            updateDroneImage(app);
        end

        % Function to update the image based on selected drone
        function updateDroneImage(app)
            % Get the selected value from the dropdown
            selectedDrone = app.DroneDropDown.Value;
            droneImage = 'Duck_Blind_Logo.jpg';            
            % Construct the full file paths for the images
            mediaPath = fullfile(fileparts(mfilename('fullpath')),'../media');

            % Construct the HTML string with image and dynamic width/height
            if strcmpi(selectedDrone, 'predator')
                droneImage = 'predatorDroneImage.jpg';  % Make sure this is the correct image file name                
            elseif strncmpi(selectedDrone, 'quad', 4)
                % Define the image filenames (ensure these files are in the same folder as your .mlapp)
                droneImage = 'quadcopterDroneImage.jpg';  % Make sure this is the correct image file name
            end
            droneImagePath = fullfile(mediaPath, droneImage);
            app.DroneImage.ImageSource = droneImagePath;

            % Update the HTML component with the new image
            % app.HTML.HTMLSource = sprintf('<html><body>%s</body></html>',imgHTML);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Set the number of drones and laser type based on user input
            numDrones = app.DroneCountEditField.Value;
            droneType = app.DroneDropDown.Value;
            laserType = app.LaserDropDown.Value;

            % Configure the simulator
            app.engine.setNumDrones(numDrones);
            app.engine.setDroneType(droneType);
            app.engine.setLaserType(laserType);
            dur_sec = 10;

            % Start the simulation (you could choose to run it in the background)
            app.engine.demo_run(dur_sec, laserType, droneType, numDrones);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.engine.reset();  % Reset the simulation to its initial state
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DuckShooterUIFigure = uifigure('Visible', 'off');
            app.DuckShooterUIFigure.Name = 'Duck Shooter';
            app.DuckShooterUIFigure.Icon = '/Users/known/git/se4003_fall_2024/figures/duckIcon.jpg';
            app.DuckShooterUIFigure.Tag = 'Duck Blind ';
            app.GridLayout = uigridlayout(app.DuckShooterUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.LaserTemperatureGaugeLabel = uilabel(app.GridLayout3);
            app.LaserTemperatureGaugeLabel.Text = 'Laser Temperature';
            app.LaserTemperatureGauge = uigauge(app.GridLayout3, 'circular');
            app.LaserPowerLevelGaugeLabel = uilabel(app.GridLayout3);
            app.LaserPowerLevelGaugeLabel.Text = 'Laser Power Level';
            app.LaserPowerLevelGauge = uigauge(app.GridLayout3, 'linear');
            app.Panel = uipanel(app.GridLayout);
            app.LaserDropDownLabel = uilabel(app.Panel);
            app.LaserDropDownLabel.Text = 'Laser';
            app.LaserDropDown = uidropdown(app.Panel);
            app.LaserDropDown.Items = {'Laser A', 'Laser B', 'Dev Laser'};
            app.LaserDropDown.Value = 'Laser A';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SimulationControlLabel = uilabel(app.Panel);
            app.SimulationControlLabel.Text = 'Simulation Control';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DroneCountEditFieldLabel = uilabel(app.Panel);
            app.DroneCountEditFieldLabel.Text = 'Drone Count';
            app.DroneCountEditField = uieditfield(app.Panel, 'numeric');
            app.DroneCountEditField.Value = 4;
            app.SimulationInputsLabel = uilabel(app.Panel);
            app.SimulationInputsLabel.Text = 'Simulation Inputs';
            app.EndButton = uibutton(app.Panel, 'push');
            app.EndButton.Text = 'End';
            app.ShowAnimationCheckBox = uicheckbox(app.Panel);
            app.ShowAnimationCheckBox.Text = 'Show Animation';
            app.ShowAnimationCheckBox.Value = true;
            app.SimulationResultsLabel = uilabel(app.Panel);
            app.SimulationResultsLabel.Text = 'Simulation Results';
            app.DronesDestroyedEditFieldLabel = uilabel(app.Panel);
            app.DronesDestroyedEditFieldLabel.Text = 'Drones Destroyed';
            app.DronesDestroyedEditField = uieditfield(app.Panel, 'numeric');
            app.DronesEngagedEditFieldLabel = uilabel(app.Panel);
            app.DronesEngagedEditFieldLabel.Text = 'Drones Engaged';
            app.DronesEngagedEditField = uieditfield(app.Panel, 'numeric');
            app.PercentKilledEditFieldLabel = uilabel(app.Panel);
            app.PercentKilledEditFieldLabel.Text = 'Percent Killed';
            app.PercentKilledEditField = uieditfield(app.Panel, 'numeric');
            app.DroneDropDownLabel = uilabel(app.Panel);
            app.DroneDropDownLabel.Text = 'Drone';
            app.DroneDropDown = uidropdown(app.Panel);
            app.DroneDropDown.Items = {'Predator', 'Quadcopter'};
            app.DroneDropDown.ValueChangedFcn = createCallbackFcn(app, @DroneDropDownValueChanged, true);
            app.DroneDropDown.Value = 'Predator';
            app.DroneImage = uiimage(app.Panel);
            app.DroneImage.ImageSource = 'Duck_Blind_Logo.jpg';
            % Show the figure after all components are created
            app.DuckShooterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = duckShooterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DuckShooterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DuckShooterUIFigure)
        end
    end
end